classdef BrainDetectionUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TumourDetectionappLabel  matlab.ui.control.Label
        LoadImageButton          matlab.ui.control.Button
        ProcessImageButton       matlab.ui.control.Button
        DownloadImageButton      matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [File_Name,Path] = uigetfile({'*.jpg';'*.png*';},'Image Selector');
            A = importdata(fullfile(Path, File_Name), '\t', 15);
            if  File_Name==0
                h = errordlg('Image not uploaded');
            else
                whos File_Name
            figure
            imshow(File_Name, 'Parent',app.UIAxes)
            end
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            imshow('mri3.jpg','Parent',app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TumourDetectionappLabel = uilabel(app.UIFigure);
            app.TumourDetectionappLabel.Text = 'Tumour Detection app';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.DownloadImageButton = uibutton(app.UIFigure, 'push');
            app.DownloadImageButton.Text = {'Download Image'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MRI Scan')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Processed Images')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainDetectionUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end